classdef Digitizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DigitizerUIFigure         matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenImageMenu             matlab.ui.container.Menu
        SaveDataMenu              matlab.ui.container.Menu
        UIAxes                    matlab.ui.control.UIAxes
        SelectanimagefileLabel    matlab.ui.control.Label
        CoordinatesystemPanel     matlab.ui.container.Panel
        ChooseAnchorPointsButton  matlab.ui.control.StateButton
        logCheckBoxY              matlab.ui.control.CheckBox
        logCheckBoxX              matlab.ui.control.CheckBox
        X1EditFieldLabel          matlab.ui.control.Label
        X1EditField               matlab.ui.control.NumericEditField
        X2EditFieldLabel          matlab.ui.control.Label
        X2EditField               matlab.ui.control.NumericEditField
        Y1EditFieldLabel          matlab.ui.control.Label
        Y1EditField               matlab.ui.control.NumericEditField
        Y2EditFieldLabel          matlab.ui.control.Label
        Y2EditField               matlab.ui.control.NumericEditField
        DatamanipulationPanel     matlab.ui.container.Panel
        AddPointsButton           matlab.ui.control.StateButton
        RemovePointsButton        matlab.ui.control.StateButton
        DataSeriesListBoxLabel    matlab.ui.control.Label
        DataSeriesListBox         matlab.ui.control.ListBox
        AddSeriesButton           matlab.ui.control.Button
        RemoveSeriesButton        matlab.ui.control.Button
        EditButton                matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        im = []; % image matrix
        anchorPoints = nan(1,4); % anchor points | Xstart Xend Ystart Yend
        anchorCounter = 1;
        inputStatus = 0; % 0 idle, 1 anchor, 2 data
        selectedSeries = 1;
        numberOfSeries = 1;
        data = {[]};
        dataCounter = {1};
        imHandle = [];
        coordinateFrame = {};
        realData = {[]};
        seriesName = {'series1'};
        webWindowHandle = [];
        x1 = []; x2 = []; y1 =[]; y2 = [];
    end
    
    
    methods (Access = private)
        
        function pos = ImButtonDownFcn(app,event)
            pos = event.IntersectionPoint(1:2) + [2 -2];
            switch app.inputStatus
                case 0
                    
                case 1 % Anchoring
                    
                    app.anchorPoints(app.selectedSeries,app.anchorCounter + [0 2]) = pos;
                    app.anchorCounter = app.anchorCounter + 1;
                    if app.anchorCounter > 2
                        app.anchorCounter = 1;
                        app.ChooseAnchorPointsButton.Value = false;
                        app.imHandle.HitTest = 'off';
                        set(app.CoordinatesystemPanel.Children,'Enable',true);
                        set(findobj(app.DatamanipulationPanel.Children,'-not','Type','uitable'),...
                            'Enable',true);
                        app.UITable.Enable = 'on';
                        app.inputStatus = 0;
                    end
                    
                case 2 % Data extraction
                    
                    tempData = [app.data{app.selectedSeries}; pos];
                    app.data{app.selectedSeries} = sortrows(tempData,1,'ascend');
                    app.dataCounter{app.selectedSeries} = app.dataCounter{app.selectedSeries} + 1;
       
                otherwise
                     % do nothing
            end
            
            app.UpdateData;
            
            % plot
            app.PlotUpdate;
            
        end
    
        function UpdateData(app)
            try
                coordinateFrameX = app.coordinateFrame{app.selectedSeries}{1};
                coordinateFrameY = app.coordinateFrame{app.selectedSeries}{2};
                
                % convert selected points to real data
                app.realData{app.selectedSeries} = [coordinateFrameX(app.data{app.selectedSeries}(:,1)) ,...
                    coordinateFrameY(app.data{app.selectedSeries}(:,2))];
                app.UITable.Data = app.realData{app.selectedSeries};
            catch
            end
        end
    
        function PlotUpdate(app)
            
            % delete all children
            delete(findobj(app.UIAxes,'Type','line'));
            cols = lines(100);
            hold(app.UIAxes,'on');
            
            try
                iSeries = app.selectedSeries;
                plot(app.UIAxes, app.anchorPoints(iSeries,1:2),app.anchorPoints(iSeries,3:4),...
                    '+','Color',[1 .6 0],'MarkerSize',12,'LineWidth',3);
                
                % existing symbols sometimes prevents user to click on overlapping data points. 
                % so we only display the releveant series
                if app.inputStatus == 2
                    seriesToPlot = app.selectedSeries;
                elseif app.inputStatus == 1
                    seriesToPlot = [];
                else
                    seriesToPlot = 1:app.numberOfSeries;
                end
               
                for i = seriesToPlot
                    try
                        msize = 5;
                        if i == app.selectedSeries
                            msize = 10;
                        end
                        plot(app.UIAxes, app.data{i}(:,1),app.data{i}(:,2),...
                            'o','Color',cols(i,:),'MarkerFaceColor',cols(i,:),...
                            'MarkerSize',msize);
                    catch
                        % for empty series
                    end
                end
            catch 
            end
                            
        end
    
        function ExtractCoordinateFrame(app)
            x1_ = app.X1EditField.Value;
            x2_ = app.X2EditField.Value;
            y1_ = app.Y1EditField.Value;
            y2_ = app.Y2EditField.Value;
            app.x1(app.selectedSeries) = x1_;
            app.x2(app.selectedSeries) = x2_;
            app.y1(app.selectedSeries) = y1_;
            app.y2(app.selectedSeries) = y2_;
            
            iSeries = app.selectedSeries;
            adx = app.anchorPoints(iSeries,2) - app.anchorPoints(iSeries,1);
            ady = app.anchorPoints(iSeries,4) - app.anchorPoints(iSeries,3);
            
            % horizontal axis.
            if app.logCheckBoxX.Value 
                xFun = @(a) 10.^(log10(x1_) + log10(x2_/x1_)*(a - app.anchorPoints(iSeries,1))/adx);
            else
                xFun = @(a) x1_ + (x2_-x1_)*(a - app.anchorPoints(iSeries,1))/adx;
            end
            
            % vertical axis
            if app.logCheckBoxY.Value 
                yFun = @(a) 10.^(log10(y1_) + log10(y2_/y1_)*(a - app.anchorPoints(iSeries,3))/ady);
            else
                yFun = @(a) y1_ + (y2_-y1_)*(a - app.anchorPoints(iSeries,3))/ady;
            end
            
            app.coordinateFrame{app.selectedSeries} = {xFun yFun};
        end


    end
    

    methods (Access = private)

        % Value changed function: ChooseAnchorPointsButton
        function ChooseAnchorPointsButtonValueChanged(app, event)
            
            % first check if we have an image loaded.
            if isempty(app.im)
                uialert(app.DigitizerUIFigure, 'No image found for anchoring.', 'Missing image error');
                app.ChooseAnchorPointsButton.Value = ~app.ChooseAnchorPointsButton.Value;
                return;
            end
            
            value = app.ChooseAnchorPointsButton.Value;
            if value
                app.imHandle.HitTest = 'on';
                app.inputStatus = 1;
                set(app.CoordinatesystemPanel.Children,'Enable',false);
                set(findobj(app.DatamanipulationPanel.Children,'-not','Type','uitable'),...
                    'Enable',false);
                app.UITable.Enable = 'off';
                app.ChooseAnchorPointsButton.Enable = true;
                
            else
                app.imHandle.HitTest = 'off';
                app.inputStatus = 0;
                set(app.CoordinatesystemPanel.Children,'Enable',true);
                set(findobj(app.DatamanipulationPanel.Children,'-not','Type','uitable'),...
                    'Enable',true);
                app.UITable.Enable = 'on';
            end
        end

        % Value changed function: AddPointsButton
        function AddPointsButtonValueChanged(app, event)
            
            % first check for anchor points
            if isempty(app.coordinateFrame)
                uialert(app.DigitizerUIFigure, 'No anchor points found.', 'Missing anchors.');
                app.AddPointsButton.Value = ~app.AddPointsButton.Value;
                return;
            elseif isempty(app.coordinateFrame{app.selectedSeries})
                uialert(app.DigitizerUIFigure, 'No anchor points found.', 'Missing anchors.');
                app.AddPointsButton.Value = ~app.AddPointsButton.Value;
                return;
            end
            
            % check if there is a series. if not, create one.
            if app.numberOfSeries == 0
                app.AddSeriesButtonPushed;
            end
                
            
            value = app.AddPointsButton.Value;
            
            if value
                app.imHandle.HitTest = 'on';
                app.inputStatus = 2;
                
                % disable other UI elements but this one
                set(app.CoordinatesystemPanel.Children,'Enable',false);
                set(findobj(app.DatamanipulationPanel.Children,'-not','Type','uitable'),...
                    'Enable',false);
                app.UITable.Enable = 'off';
                app.AddPointsButton.Enable = true;
                
            else
                app.imHandle.HitTest = 'off';
                app.inputStatus = 0;
                
                % enable other UI elements
                set(app.CoordinatesystemPanel.Children,'Enable',true);
                set(findobj(app.DatamanipulationPanel.Children,'-not','Type','uitable'),...
                    'Enable',true);
                app.UITable.Enable = 'on';
            end
        end

        % Value changed function: RemovePointsButton
        function RemovePointsButtonValueChanged(app, event)
            
            % first check for anchor points
            if isempty(app.coordinateFrame)
                uialert(app.DigitizerUIFigure, 'No anchor points found.', 'Missing anchors.');
                app.RemovePointsButton.Value = ~app.RemovePointsButton.Value;
                return;
                
            elseif isempty(app.coordinateFrame{app.selectedSeries})
                uialert(app.DigitizerUIFigure, 'No anchor points found.', 'Missing anchors.');
                app.AddPointsButton.Value = ~app.AddPointsButton.Value;
                return;
            end
            
            % check if there is a series. if not, return.
            if app.numberOfSeries == 0
                app.RemovePointsButton.Value = ~app.RemovePointsButton.Value;
                return;
            end
            
            value = app.RemovePointsButton.Value;
            
            if value
                app.imHandle.HitTest = 'on';
                app.inputStatus = 2;
                
                % disable other UI elements but this one
                set(app.CoordinatesystemPanel.Children,'Enable',false);
                set(findobj(app.DatamanipulationPanel.Children,'-not','Type','uitable'),...
                    'Enable',false);
                app.UITable.Enable = 'on';
                app.RemovePointsButton.Enable = true;
                
            else
                app.imHandle.HitTest = 'off';
                app.inputStatus = 0;
                
                % enable other UI elements
                set(app.CoordinatesystemPanel.Children,'Enable',true);
                set(findobj(app.DatamanipulationPanel.Children,'-not','Type','uitable'),...
                    'Enable',true);
                app.UITable.Enable = 'on';
                
            end
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
            
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Value changed function: logCheckBoxY
        function logCheckBoxYValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Value changed function: Y2EditField
        function Y2EditFieldValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Value changed function: Y1EditField
        function Y1EditFieldValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Value changed function: logCheckBoxX
        function logCheckBoxXValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if app.RemovePointsButton.Value
                indices = event.Indices;
                tempData = app.realData{app.selectedSeries};
                tempData(indices(:,1),:) = [];
                app.realData{app.selectedSeries} = tempData;
                app.UITable.Data = app.realData{app.selectedSeries};
                pxData = app.data{app.selectedSeries};
                pxData(indices(:,1),:) = [];
                app.data{app.selectedSeries} = pxData;
                app.dataCounter{app.selectedSeries} = size(pxData,1);
                app.PlotUpdate;
            end
            
        end

        % Menu selected function: OpenImageMenu
        function OpenImageMenuSelected(app, event)
            
%             if ~isempty(app.im)
%                 selection = uiconfirm(app.DigitizerUIFigure,...
%                     ['Loading another image will lose all extracted'...
%                     ' data and settings. Do you want to continue?'] ...
%                     ,'Confirm Close','Icon','warning','DefaultOption',2);
%                 switch selection
%                     case 'Cancel'
%                         return;
%                     otherwise
%                 end
%             end
                        
            %%% HACK: Needed to get the dialog box in front of the GUI
            if isempty(app.webWindowHandle)
                % source of the method:  https://www.reddit.com/r/matlab/comments/92bh7e/force_uigetfile_to_open_dialogue_in_front_of/e34kzvn/
                webWindows = matlab.internal.webwindowmanager.instance.windowList;
                for ii = 1:length(webWindows)
                    webWindow = webWindows(ii);
                    if strcmp( webWindow.Title, app.DigitizerUIFigure.Name )
                        break;
                    end
                end
                app.webWindowHandle = webWindow;
            else
                webWindow = app.webWindowHandle;
            end

            % ask user to select an image file
            webWindow.hide;
            [file,path] = uigetfile({'*.jpg;*.jpeg;*.png;*.pdf;*.tif;*.tiff;*.bmp'},...
                'Select an image file','MultiSelect', 'off');
            app.SelectanimagefileLabel.Text = sprintf('%s%s\n',path,file);
           
            webWindow.show;
            webWindow.bringToFront;
            %
            %%% end of HACK
  
            
            try
                if ~isempty(file) && ischar(file)
                    % check if there is already an image
                    app.im = []; % image matrix
                    app.imHandle = [];
                    if ~isempty(app.coordinateFrame)
                        app.AddSeriesButtonPushed;
                    end
                    app.PlotUpdate;
                else
                    % user canceled file selection
                    return;
                end
                
                app.im = imread([path file]);
                cla(app.UIAxes);
                app.imHandle = imshow(app.im, 'Border', 'tight','Parent',app.UIAxes);
                app.imHandle.HitTest = 'off';
                app.imHandle.ButtonDownFcn = @(~,e) app.ImButtonDownFcn(e);
                app.UITable.ColumnName = {'X','Y'};
                app.UITable.ColumnEditable = true;
                app.UITable.Data = [];
                
            catch ME
                % If problem reading image, display error message
                uialert(app.DigitizerUIFigure, ME.message, 'Image Error');
                return;
            end
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuSelected(app, event)
            
            if isempty(app.im)
                % If problem reading image, display error message
                uialert(app.DigitizerUIFigure, 'Nothing to save', 'Image Error');
                return;
            end
            
            %%% HACK: Needed to get the dialog box in front of the GUI
            webWindow = app.webWindowHandle;
            webWindow.hide;
            
            % ask user to input a file name to
            [file,path,~] = uiputfile('data.mat');
           
            webWindow.show;
            webWindow.bringToFront;
            %%% end of HACK
            
            
            if ~isempty(file) && ischar(file)
                myData = [];
                for i=1:app.numberOfSeries
                    try
                        myData.(app.seriesName{i}) = app.realData{i};
                    catch
                        % probably an empty series encountered
                    end
                end
                save([path filesep file],'-struct','myData');
            else
                return;
            end
            uialert(app.DigitizerUIFigure, 'Data saved successfully!','Success!','icon','success');
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            tData = app.realData{app.selectedSeries};
            tData(indices(1,1),indices(1,2)) = newData;
            app.realData{app.selectedSeries} = tData;
        end

        % Button pushed function: AddSeriesButton
        function AddSeriesButtonPushed(app, event)

            
            app.numberOfSeries = app.numberOfSeries + 1;
            app.selectedSeries = app.numberOfSeries;
            app.anchorPoints(app.selectedSeries,:) = app.anchorPoints(app.selectedSeries-1,:);
            app.coordinateFrame{app.selectedSeries} = app.coordinateFrame{app.selectedSeries-1}; % use anchors from previous series
            app.x1(app.selectedSeries) = app.x1(app.selectedSeries-1);
            app.x2(app.selectedSeries) = app.x2(app.selectedSeries-1);
            app.y1(app.selectedSeries) = app.y1(app.selectedSeries-1);
            app.y2(app.selectedSeries) = app.y2(app.selectedSeries-1);
            app.seriesName{app.selectedSeries} = ['series' num2str(app.selectedSeries)];
            app.data{app.selectedSeries} = [];
            app.dataCounter{app.selectedSeries} = 1;
            app.realData{app.selectedSeries} = [];
            app.DataSeriesListBox.Items = app.seriesName;
            app.DataSeriesListBox.Value = app.seriesName{app.selectedSeries};
            
            app.ExtractCoordinateFrame;
            app.UpdateData;
            app.PlotUpdate;
            
        end

        % Button pushed function: RemoveSeriesButton
        function RemoveSeriesButtonPushed(app, event)
            
            % check if there is a series. if not, return.
            if app.numberOfSeries == 0
                return;
            end

            app.coordinateFrame(app.selectedSeries) = [];
            app.seriesName(app.selectedSeries) = [];
            app.data(app.selectedSeries) = [];
            app.dataCounter(app.selectedSeries) = [];
            app.realData(app.selectedSeries) = [];
            app.numberOfSeries = app.numberOfSeries - 1;
            app.selectedSeries = app.numberOfSeries;
            app.DataSeriesListBox.Items = app.seriesName;
            if app.selectedSeries == 0
                app.DataSeriesListBox.Value = {};
                app.UITable.Data = [];
            else
                app.DataSeriesListBox.Value = app.seriesName{app.selectedSeries};
            end
            app.PlotUpdate;
            
        end

        % Value changed function: DataSeriesListBox
        function DataSeriesListBoxValueChanged(app, event)
            ix = find(strcmp(app.DataSeriesListBox.Items,app.DataSeriesListBox.Value));
            app.selectedSeries = ix;
            app.UITable.Data = app.realData{app.selectedSeries};
            app.X1EditField.Value = app.x1(app.selectedSeries);
            app.X2EditField.Value = app.x2(app.selectedSeries);
            app.Y1EditField.Value = app.y1(app.selectedSeries);
            app.Y2EditField.Value = app.y2(app.selectedSeries);
            app.PlotUpdate;
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            answer = inputdlg('Enter a data series name','Edit data series name',[1 50],{'seriesX'});
            if ~isempty(answer) 
                if isvarname(answer{1})
                    app.DataSeriesListBox.Items{app.selectedSeries} = answer{1};
                    app.seriesName{app.selectedSeries} = answer{1};
                    app.DataSeriesListBox.Value = answer{1};
                else
                    uialert(app.DigitizerUIFigure, 'Please enter a valid variable name.', ...
                        'Variable Name Error');
                end
            end
            webWindow = app.webWindowHandle;
            webWindow.bringToFront;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DigitizerUIFigure = uifigure;
            app.DigitizerUIFigure.Name = 'Digitizer';
            app.FileMenu = uimenu(app.DigitizerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenImageMenu = uimenu(app.FileMenu);
            app.OpenImageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenImageMenuSelected, true);
            app.OpenImageMenu.Text = 'Open Image';
            app.SaveDataMenu = uimenu(app.FileMenu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveDataMenu.Text = 'Save Data';
            app.UIAxes = uiaxes(app.DigitizerUIFigure);
            title(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SelectanimagefileLabel = uilabel(app.DigitizerUIFigure);
            app.SelectanimagefileLabel.Text = 'Select an image file.';
            app.CoordinatesystemPanel = uipanel(app.DigitizerUIFigure);
            app.CoordinatesystemPanel.Title = 'Coordinate system';
            app.ChooseAnchorPointsButton = uibutton(app.CoordinatesystemPanel, 'state');
            app.ChooseAnchorPointsButton.ValueChangedFcn = createCallbackFcn(app, @ChooseAnchorPointsButtonValueChanged, true);
            app.ChooseAnchorPointsButton.Tooltip = {'Select two points on the image and enter the corresponding coordinates below.'};
            app.ChooseAnchorPointsButton.Text = 'Choose Anchor Points';
            app.logCheckBoxY = uicheckbox(app.CoordinatesystemPanel);
            app.logCheckBoxY.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxYValueChanged, true);
            app.logCheckBoxY.Text = 'log';
            app.logCheckBoxX = uicheckbox(app.CoordinatesystemPanel);
            app.logCheckBoxX.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxXValueChanged, true);
            app.logCheckBoxX.Text = 'log';
            app.X1EditFieldLabel = uilabel(app.CoordinatesystemPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.CoordinatesystemPanel, 'numeric');
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X2EditFieldLabel = uilabel(app.CoordinatesystemPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.CoordinatesystemPanel, 'numeric');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.Y1EditFieldLabel = uilabel(app.CoordinatesystemPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.CoordinatesystemPanel, 'numeric');
            app.Y1EditField.ValueChangedFcn = createCallbackFcn(app, @Y1EditFieldValueChanged, true);
            app.Y2EditFieldLabel = uilabel(app.CoordinatesystemPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.CoordinatesystemPanel, 'numeric');
            app.Y2EditField.ValueChangedFcn = createCallbackFcn(app, @Y2EditFieldValueChanged, true);
            app.DatamanipulationPanel = uipanel(app.DigitizerUIFigure);
            app.DatamanipulationPanel.Title = 'Data manipulation';
            app.AddPointsButton = uibutton(app.DatamanipulationPanel, 'state');
            app.AddPointsButton.ValueChangedFcn = createCallbackFcn(app, @AddPointsButtonValueChanged, true);
            app.AddPointsButton.Text = 'Add Points';
            app.RemovePointsButton = uibutton(app.DatamanipulationPanel, 'state');
            app.RemovePointsButton.ValueChangedFcn = createCallbackFcn(app, @RemovePointsButtonValueChanged, true);
            app.RemovePointsButton.Text = 'Remove Points';
            app.DataSeriesListBoxLabel = uilabel(app.DatamanipulationPanel);
            app.DataSeriesListBoxLabel.Text = 'Data Series';
            app.DataSeriesListBox = uilistbox(app.DatamanipulationPanel);
            app.DataSeriesListBox.Items = {'series1'};
            app.DataSeriesListBox.ValueChangedFcn = createCallbackFcn(app, @DataSeriesListBoxValueChanged, true);
            app.DataSeriesListBox.Value = 'series1';
            app.AddSeriesButton = uibutton(app.DatamanipulationPanel, 'push');
            app.AddSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @AddSeriesButtonPushed, true);
            app.AddSeriesButton.Text = 'Add Series';
            app.RemoveSeriesButton = uibutton(app.DatamanipulationPanel, 'push');
            app.RemoveSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSeriesButtonPushed, true);
            app.RemoveSeriesButton.Text = 'Remove Series';
            app.EditButton = uibutton(app.DatamanipulationPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.UITable = uitable(app.DatamanipulationPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Digitizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DigitizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DigitizerUIFigure)
        end
    end
end